classdef FinaleProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RunTimesecTextArea          matlab.ui.control.TextArea
        RunTimesecTextAreaLabel     matlab.ui.control.Label
        BYBenEfrathBarYerushalmianLabel  matlab.ui.control.Label
        StereoMatchingObjectsDistanceFromCameraLabel  matlab.ui.control.Label
        BaseLinecmTextArea          matlab.ui.control.TextArea
        BaseLinecmTextAreaLabel     matlab.ui.control.Label
        readyLamp                   matlab.ui.control.Lamp
        readyLampLabel              matlab.ui.control.Label
        KnownDistancesTextArea      matlab.ui.control.TextArea
        KnownDistancesLabel         matlab.ui.control.Label
        DistancecmTextArea          matlab.ui.control.TextArea
        DistancecmLabel             matlab.ui.control.Label
        STARTButton                 matlab.ui.control.Button
        FocalLengthPixelsTextArea   matlab.ui.control.TextArea
        FocalLengthPixelsLabel      matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ChoosePictureDropDown       matlab.ui.control.DropDown
        ChoosePictureDropDownLabel  matlab.ui.control.Label
        ChooseCameraDropDown        matlab.ui.control.DropDown
        ChooseCameraLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)

        f;
        Ileft;
        Iright;
        T;
        Z;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChooseCameraDropDown
        function ChooseCameraDropDownValueChanged(app, event)
            value = app.ChooseCameraDropDown.Value;
              
            if value == "Galaxy S10"
                app.f=3.115634632255218e+03;
            end
            if value == "Iphone 12 Pro"
                app.f=3.001726207588778e+03;
            end
            app.FocalLengthPixelsTextArea.Value=num2str(app.f);
        end

        % Clicked callback: ChooseCameraDropDown
        function ChooseCameraDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: ChoosePictureDropDown
        function ChoosePictureDropDownValueChanged(app, event)
            value = app.ChoosePictureDropDown.Value;
            if app.ChooseCameraDropDown.Value=="Galaxy S10"
                switch value
                    case "Picture 1"
                        app.Ileft=imread("Ben1L.jpg");
                        app.Iright=imread("Ben1R.jpg");
                        app.T=8;
                        app.KnownDistancesTextArea.Value=sprintf("Soda=84cm\nCoke=115cm");
                    
                    case "Picture 2"
                        app.Ileft=imread("Ben2L.jpg");
                        app.Iright=imread("Ben2R.jpg");
                        app.T=25;
                        app.KnownDistancesTextArea.Value=sprintf("Whey=111.5cm\nScissors=115cm\nBamba=167cm\nCandle=235cm");

                    case "Picture 3"
                        app.Ileft=imread("Ben3L.jpg");
                        app.Iright=imread("Ben3R.jpg");
                        app.T=15;
                        app.KnownDistancesTextArea.Value=sprintf("Whey=108cm\nBag=202cm\nMatress=226cm");
                    case "Picture 4"
                        app.Ileft=imread("Ben4L.jpg");
                        app.Iright=imread("Ben4R.jpg");
                        app.T=25;
                        app.KnownDistancesTextArea.Value=sprintf("Scotch=196cm\nBamba 213cm\nProtein=125cm");
                end
            end
            if app.ChooseCameraDropDown.Value=="Iphone 12 Pro"
                switch value
                    case "Picture 1"
                        app.Ileft=imread("Bar1L.jpg");
                        app.Iright=imread("Bar1R.jpg");
                        app.T=42;
                        app.KnownDistancesTextArea.Value=sprintf("Scotch=76cm");
                    
                    case "Picture 2"
                        app.Ileft=imread("Bar2L.jpg");
                        app.Iright=imread("Bar2R.jpg");
                        app.T=25;
                        app.KnownDistancesTextArea.Value=sprintf("Scotch=82cm\nLion=120cm");

                   case "Picture 3"
                        app.Ileft=imread("Bar3L.jpg");
                        app.Iright=imread("Bar3R.jpg");
                        app.T=25;
                        app.KnownDistancesTextArea.Value=sprintf("Scotch=82cm\nLion=125cm");
                   case "Picture 4"
                        app.Ileft=imread("Bar4L.jpg");
                        app.Iright=imread("Bar4R.jpg");
                        app.T=18;
                        app.KnownDistancesTextArea.Value=sprintf("Martini=83cm\nSmall Bottle=57cm\nCan=64cm");
                end
            end
          
          app.BaseLinecmTextArea.Value=num2str(app.T);
          app.Image.ImageSource=app.Ileft;
        end

        % Clicked callback: ChoosePictureDropDown
        function ChoosePictureDropDownClicked(app, event)
            item = event.InteractionInformation.Item;

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.readyLamp.Color='red';
            if app.Image.ImageSource==0
                app.readyLamp.Color='green';
                return
            end
            
            [app.Z,time]=DistanceCalc(app.Ileft,app.Iright,app.f,app.T);
            app.RunTimesecTextArea.Value=num2str(time);
            app.DistancecmTextArea.Value=num2str(app.Z);
            app.readyLamp.Color='green';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseCameraLabel = uilabel(app.UIFigure);
            app.ChooseCameraLabel.Text = 'Choose Camera:';
            app.ChooseCameraDropDown = uidropdown(app.UIFigure);
            app.ChooseCameraDropDown.Items = {'None', 'Iphone 12 Pro', 'Galaxy S10'};
            app.ChooseCameraDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseCameraDropDownValueChanged, true);
            app.ChooseCameraDropDown.ClickedFcn = createCallbackFcn(app, @ChooseCameraDropDownClicked, true);
            app.ChooseCameraDropDown.Value = 'None';
            app.ChoosePictureDropDownLabel = uilabel(app.UIFigure);
            app.ChoosePictureDropDownLabel.Text = 'Choose Picture:';
            app.ChoosePictureDropDown = uidropdown(app.UIFigure);
            app.ChoosePictureDropDown.Items = {'None', 'Picture 1', 'Picture 2', 'Picture 3', 'Picture 4'};
            app.ChoosePictureDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosePictureDropDownValueChanged, true);
            app.ChoosePictureDropDown.ClickedFcn = createCallbackFcn(app, @ChoosePictureDropDownClicked, true);
            app.ChoosePictureDropDown.Value = 'None';
            app.Image = uiimage(app.UIFigure);
            app.FocalLengthPixelsLabel = uilabel(app.UIFigure);
            app.FocalLengthPixelsLabel.Text = 'Focal Length [Pixels]:';
            app.FocalLengthPixelsTextArea = uitextarea(app.UIFigure);
            app.FocalLengthPixelsTextArea.Editable = 'off';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.DistancecmLabel = uilabel(app.UIFigure);
            app.DistancecmLabel.Text = 'Distance [cm]:';
            app.DistancecmTextArea = uitextarea(app.UIFigure);
            app.DistancecmTextArea.Editable = 'off';
            app.KnownDistancesLabel = uilabel(app.UIFigure);
            app.KnownDistancesLabel.Text = 'Known Distances:';
            app.KnownDistancesTextArea = uitextarea(app.UIFigure);
            app.KnownDistancesTextArea.Editable = 'off';
            app.readyLampLabel = uilabel(app.UIFigure);
            app.readyLampLabel.Text = 'ready';
            app.readyLamp = uilamp(app.UIFigure);
            app.BaseLinecmTextAreaLabel = uilabel(app.UIFigure);
            app.BaseLinecmTextAreaLabel.Text = 'BaseLine [cm]:';
            app.BaseLinecmTextArea = uitextarea(app.UIFigure);
            app.BaseLinecmTextArea.Editable = 'off';
            app.StereoMatchingObjectsDistanceFromCameraLabel = uilabel(app.UIFigure);
            app.StereoMatchingObjectsDistanceFromCameraLabel.Text = 'Stereo Matching: Object''s Distance From Camera';
            app.BYBenEfrathBarYerushalmianLabel = uilabel(app.UIFigure);
            app.BYBenEfrathBarYerushalmianLabel.Text = {' BY:'; ''; 'Ben Efrath'; '&'; 'Bar Yerushalmian'};
            app.RunTimesecTextAreaLabel = uilabel(app.UIFigure);
            app.RunTimesecTextAreaLabel.Text = 'RunTime [sec]:';
            app.RunTimesecTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinaleProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end